classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        rsEditField               matlab.ui.control.NumericEditField
        rsEditFieldLabel          matlab.ui.control.Label
        rpEditField               matlab.ui.control.NumericEditField
        rpEditFieldLabel          matlab.ui.control.Label
        transitionbandwidthLabel  matlab.ui.control.Label
        trst_bdwth                matlab.ui.control.NumericEditField
        AfterfilteringLabel       matlab.ui.control.Label
        saveButton                matlab.ui.control.Button
        stopButton_3              matlab.ui.control.Button
        palyButton                matlab.ui.control.Button
        NEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel           matlab.ui.control.Label
        bandwidthLabel            matlab.ui.control.Label
        bandwidthTextArea         matlab.ui.control.TextArea
        fHEditField               matlab.ui.control.NumericEditField
        fHEditFieldLabel          matlab.ui.control.Label
        fLEditField               matlab.ui.control.NumericEditField
        fLEditFieldLabel          matlab.ui.control.Label
        stopButton_2              matlab.ui.control.Button
        playButton                matlab.ui.control.Button
        afterfilteringLabel       matlab.ui.control.Label
        timeTextArea_2            matlab.ui.control.TextArea
        timeTextArea_2Label       matlab.ui.control.Label
        generateButton_F          matlab.ui.control.Button
        generateButton_T          matlab.ui.control.Button
        currentfile               matlab.ui.control.TextArea
        currentfileTextAreaLabel  matlab.ui.control.Label
        Filt_Start                matlab.ui.control.Button
        filterButtonGroup         matlab.ui.container.ButtonGroup
        BandstopfilterButton      matlab.ui.control.RadioButton
        BandpassfilterButton      matlab.ui.control.RadioButton
        HighpassfilterButton      matlab.ui.control.RadioButton
        LowpassfilterButton       matlab.ui.control.RadioButton
        SpectrumLabel             matlab.ui.control.Label
        TimedomainwaveformLabel   matlab.ui.control.Label
        inputsourceLabel          matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        fileTab                   matlab.ui.container.Tab
        FileDisplay               matlab.ui.control.TextArea
        uploadButton              matlab.ui.control.Button
        choosethefileButton       matlab.ui.control.Button
        realtimeTab               matlab.ui.container.Tab
        sLabel                    matlab.ui.control.Label
        timeTextArea              matlab.ui.control.TextArea
        timeTextAreaLabel         matlab.ui.control.Label
        stopButton                matlab.ui.control.Button
        startButton               matlab.ui.control.Button
        filterShow                matlab.ui.control.UIAxes
        afterF_axes_r             matlab.ui.control.UIAxes
        afterF_axes_l             matlab.ui.control.UIAxes
        SpectrumAxes_R            matlab.ui.control.UIAxes
        DomainAxes_R              matlab.ui.control.UIAxes
        SpectrumAxes_L            matlab.ui.control.UIAxes
        DomainAxes_L              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %general
        y_L       double    % left
        y_R       double    % right channel   divide 
        Fs        double
        T         double
        Source    int8   % 0= from file  1= from record
        
        %filter
        fl        double
        fh        double 
        N         double   % jieshu
        Bandwidth double

        flt_stt   int8    %state
%         1             2         3          4    filter_state 
%         lowpass   highpass   bandpass  bandstop
        flt_y     double 
        flt_y_L   double 
        flt_y_R   double 
        t_f         int16
        rp          double
        rs          double 
        trst_bdw  double  % transition bandwidth 过渡带宽
        %file  load
        y         double    % two channel  
        FileName  string
        FilePath  string
        
        % record 
        rcd_state int8   % 1= on 0= off  
        record    double   % record two channel 
        t_r       int16     %times


        %timer 
        time_s    double
        timer_id
       
    end
    
    methods (Access = private)
        
        function timer_init(app)
            app.timer_id=timer;
            app.timer_id.StartDelay = 0;
            app.timer_id.Period = 0.03;
            app.timer_id.ExecutionMode = 'fixedSpacing';
            app.timer_id.TimerFcn = @(~, ~) timer_handler(app);
            app.timer_id.UserData = 0;    
        end

        function timer_start(app)
            start(app.timer_id);
        end

        function timer_stop(app)
            stop(app.timer_id);
        end

        function timer_handler(app)
            app.timer_id.UserData =app.timer_id.UserData+0.03;
            app.timeTextArea.Value=num2str(app.timer_id.UserData);
            
        end
        
        %adapt cheby2    
        % rp rs transitionBandwidth are in startup Fcn 
        function lpf(app,fl)    %low pass filter 
            if fl-app.trst_bdw>0
                fp=fl;
                fs=app.Fs;
                ft=fp+app.trst_bdw;
                wp=2*fp/fs;
                ws=2*ft/fs;
                [n,wn]=cheb2ord(wp,ws,app.rp,app.rs);
                if app.N>=n
                    [b,a]=cheby2(app.N,app.rs,wn,'low');
                    [H,W] = freqz(b,a);
                    plot(app.filterShow,W*fs/(2*pi),abs(H));
                    if app.Source==0   % 0= from file  1= from record
                        app.flt_y=filter(b,a,app.y);
                        dsp(app,app.flt_y);
    
                    elseif app.Source==1
                        app.flt_y=filter(b,a,app.record);
                        dsp(app,app.flt_y);
    
                    else 
                        msgbox('unexpected error');
                    end 
    
                else
                    str=['n is less than the lowest filter order:',num2str(n)];
                    msgbox(str);
                end
            else
                msgbox('fl - transition bandwidth must be more than 0 ');
            end

        end


        function hpf(app,fh)    %high pass
            fp=fh;
            fs=app.Fs;
            ft=fp-app.trst_bdw;
            wp=2*fp/fs;
            ws=2*ft/fs;
            [n,wn]=cheb2ord(wp,ws,app.rp,app.rs);
            if app.N>=n
                [b,a]=cheby2(app.N,app.rs,wn,'high');
                [H,W] = freqz(b,a);
                plot(app.filterShow,W*fs/(2*pi),abs(H));
                if app.Source==0   % 0= from file  1= from record
                    app.flt_y=filter(b,a,app.y);
                    dsp(app,app.flt_y);


                elseif app.Source==1
                    app.flt_y=filter(b,a,app.record);
                    dsp(app,app.flt_y);

                else 
                    msgbox('unexpected error');
                end 

            else
                str=['n is less than the lowest filter order:',num2str(n)];
                msgbox(str);
            end
        end


        function bpf(app,fl,fh)    % band pass
            if fl-app.trst_bdw>0
                fp=[fl,fh];
                ft=[fl-app.trst_bdw,fh+app.trst_bdw];
                fs=app.Fs;
                wp=fp/(app.Fs/2);
                ws=ft/(app.Fs/2);
               
                [n,wn]=cheb2ord(wp,ws,app.rp,app.rs);
                
                if app.N>=n
                    [b,a]=cheby2(app.N,app.rs,wn);
                    [H,W] = freqz(b,a);
                    plot(app.filterShow,W*fs/(2*pi),abs(H));
                    if app.Source==0   % 0= from file  1= from record
                        app.flt_y=filter(b,a,app.y);
                        dsp(app,app.flt_y);
    
                    elseif app.Source==1
                        app.flt_y=filter(b,a,app.record);
                        dsp(app,app.flt_y);
    
                    else 
                        msgbox('unexpected error');
                    end 
    
                else
                    str=['n is less than the lowest filter order:',num2str(n)];
                    msgbox(str);
                end
            else
                msgbox('fl - transition bandwidth must be more than 0 ');
            end
    
         end

        function bsf(app,fl,fh)    % band stop 

            fp=[fl,fh];
            ft=[fl+app.trst_bdw,fh-app.trst_bdw];
            fs=app.Fs;

            wp=fp/(app.Fs/2);
            ws=ft/(app.Fs/2);
            [n,wn]=cheb2ord(wp,ws,app.rp,app.rs);
            if app.N>=n
                [b,a]=cheby2(app.N,app.rs,wn,'stop');
                [H,W] = freqz(b,a);
                plot(app.filterShow,W*fs/(2*pi),abs(H));
                if app.Source==0   % 0= from file  1= from record
                    app.flt_y=filter(b,a,app.y);
                    dsp(app,app.flt_y);

                elseif app.Source==1
                    app.flt_y=filter(b,a,app.record);
                    dsp(app,app.flt_y);

                else 
                    msgbox('unexpected error');
                end 

            else
                str=['n is less than the lowest filter order:',num2str(n)];
                msgbox(str);
            end
        end

        function dsp(app,y)       % draw f domain in two channel 
            % divide channel 
            app.y_L=transpose(y(:,1));
            app.y_R=transpose(y(:,2));
            msgbox('L,R ok !')

            y1=fft(app.y_L);
            y2=fft(app.y_R);
            msgbox('fft complete')
            f=(0:length(app.y_L)-1)*app.Fs/length(app.y_L);
            plot(app.afterF_axes_l,f,abs(y1))
            plot(app.afterF_axes_r,f,abs(y2))

         
        end
    end
    %dsnd by meinasisannnnnnnnnn

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, None)
            
            app.FileDisplay.Value='choose a file';
            app.Fs=0;
            app.T=0;
            app.time_s=0;
            timer_init(app);
            app.rcd_state=0;
            app.flt_stt=1;
            app.N=5;
       
            app.Bandwidth=100;
            app.fl=500;
            app.fh=1000;

            app.t_r=1;
            app.t_f=1;

            app.rp=3;
            app.rs=15;
            app.trst_bdw=100;
            app.trst_bdwth.Value=app.trst_bdw;
            app.rsEditField.Value=app.rs;
            app.rpEditField.Value=app.rp;

            app.fLEditField.Value=app.fl;
            app.fHEditField.Value=app.fh;
            app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
            app.NEditField.Value=app.N;
            app.timeTextArea.Value=num2str(app.time_s);

        end

        % Button pushed function: choosethefileButton
        function choosethefileButtonPushed(app, event)
            [app.FileName,app.FilePath]=uigetfile('*.mp3;*.wav');

            if contains(app.FileName,{'.mp3';'.wav'}) %judge
                app.FilePath=app.FilePath+string+app.FileName;
                app.FileDisplay.Value=app.FilePath;
                app.currentfile.Value=app.FileName;
            else
                msgbox('invalid file format');
            end

            
        end

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)
            if isempty(app.FilePath) 
                msgbox('please choose a file');
            else
                [app.y,app.Fs]=audioread(app.FilePath);
                app.y_L=transpose(app.y(:,1));
                app.y_R=transpose(app.y(:,2));
                app.T=length(app.y)/app.Fs;
                if app.Fs==0
                    msgbox('Upload failed');
                else 
                    msgbox('Uploaded successfully');
                    app.Source=0; %file 
                end
            end

           
        end

        % Button pushed function: generateButton_F
        function generateButton_FPushed(app, event)
            if isempty(app.y) && isempty(app.record)
                msgbox('no file here , click the button upload' );
            else
                y1=fft(app.y_L);
                y2=fft(app.y_R);
                f=(0:length(app.y_L)-1)*app.Fs/length(app.y_L);
                %f=app.Fs/length(app.y_L)*(0:round(length(app.y_L)/2)-1);
                plot(app.SpectrumAxes_L,f,abs(y1));
                plot(app.SpectrumAxes_R,f,abs(y2));

            end
        end

        % Button pushed function: generateButton_T
        function generateButton_TPushed(app, event)
           if isempty(app.y) && isempty(app.record)
                msgbox('no file here , click the button upload ');
           else
            
                x=0:1/app.Fs:(app.T-1/app.Fs);
                
                plot(app.DomainAxes_L,x,app.y_L)
                plot(app.DomainAxes_R,x,app.y_R)
           end
         
        
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            
            if app.rcd_state ~=1 
                timer_start(app);               
            end
            app.rcd_state=1;

            
            recobj = audiorecorder(44100,16,2); 
            app.Fs=44100;
            recordblocking(recobj,1);   %1s 
            rdata=getaudiodata(recobj,"double");
            tmp=[];
            while true
                
                recordblocking(recobj,1);
                rdata = getaudiodata(recobj);
                tmp=[tmp;rdata];
                
                if app.rcd_state==0
                    break;
                end
                
              
            end

            app.record=tmp;
            app.Source=1;
            str=['record\record',num2str(app.t_r),'.wav'];
            audiowrite(str,app.record,app.Fs);
            str=['record',num2str(app.t_r),'.wav saved'];
            app.t_r=app.t_r+1;
            app.y_L=transpose(app.record(:,1));
            app.y_R=transpose(app.record(:,2));

            app.T=length(app.record)/app.Fs;
           
            msg=['valid record length:',num2str(app.T),'s'];
            msgbox(msg);
            app.timeTextArea_2.Value=num2str(app.T);
            
            msgbox(str);
            

        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.rcd_state=0;
            app.time_s=app.timer_id.UserData;
            stop(app.timer_id);
            set(app.timer_id,'UserData',0);
            
            app.currentfile.Value='my record';

        end

        % Button pushed function: Filt_Start
        function Filt_StartButtonPushed(app, event)
%         1             2         3          4    filter_state 
%         lowpass   highpass   bandpass  bandstop
            
            switch app.flt_stt
                case 1
                    lpf(app,app.fl);
                case 2
                    hpf(app,app.fh);
                case 3
                    bpf(app,app.fl,app.fh);
                case 4
                    bsf(app,app.fl,app.fh);
            end
        end

        % Selection changed function: filterButtonGroup
        function filterButtonGroupSelectionChanged(app, event)
            selectedButton = app.filterButtonGroup.SelectedObject;
            %         1             2         3          4    filter_state
            %         lowpass   highpass   bandpass  bandstop
            switch selectedButton.Text
                case 'Band stop filter'
                    app.flt_stt=4;
                    if app.fl>app.fh
                        msgbox('Ensure that fl is less than fh')
                        app.fl=500;
                        app.fh=1000;
                        app.fLEditField.Value=app.fl;
                        app.fHEditField.Value=app.fh;
                        app.Bandwidth=app.fh-app.fl;
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    else
                        app.Bandwidth=app.fh-app.fl;
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    end
                case 'Band pass filter'
                    app.flt_stt=3;
                    if app.fl>app.fh
                        msgbox('Ensure that fl is less than fh')
                        app.fl=500;
                        app.fh=1000;
                        app.fLEditField.Value=app.fl;
                        app.fHEditField.Value=app.fh;
                        app.Bandwidth=app.fh-app.fl;
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    else
                        app.Bandwidth=app.fh-app.fl;
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    end
                case 'High pass filter'
                    app.flt_stt=2;
                case 'Low pass filter'
                    app.flt_stt=1;
            end


        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            if isempty(app.Source)
                msgbox('need a audio file');
            elseif app.Source==0  % 0= from file  1= from record  
                sound(app.y,app.Fs);
            elseif app.Source==1
                sound(app.record,app.Fs);
            else
                msgbox('unexpected error');
            end


        end

        % Button pushed function: stopButton_2
        function stopButton_2Pushed(app, event)
            clear sound;
            
        end

        % Value changed function: fLEditField
        function fLEditFieldValueChanged(app, event)
            temp=app.fLEditField.Value;

            if temp>=0
                app.fl=temp;
               
                if app.flt_stt==3 || app.flt_stt==4
                    if app.fl<=app.fh
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    else
                        msgbox('Ensure that fl is less than fh');
                        app.fLEditField.Value=100;
                    end
                end
            else
                    msgbox('fl is more than 0');
                    app.fLEditField.Value=100;
           
            end
         
        end

        % Value changed function: fHEditField
        function fHEditFieldValueChanged(app, event)
            temp=app.fHEditField.Value;
            if temp>=0
                 app.fh=temp;
               
                 if app.flt_stt==3 || app.flt_stt==4
                    if app.fl<=temp
                        app.bandwidthTextArea.Value=num2str(app.fh-app.fl);
                    else
                        msgbox('Ensure that fl is less than fh');
                        app.fHEditField.Value=100;
                    end
                end
            else
                    msgbox('fh is more than 0');
                    app.fHEditField.Value=100;
              
            end
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            if value>=1 && rem(value,1)==0
                app.N=value;

            else
                msgbox('N must be an integer more than one');
                app.NEditField.Value=app.N;
            end

            
        end

        % Button pushed function: palyButton
        function palyButtonPushed(app, event)
            if  isempty(app.flt_y)
                msgbox('need a file after filtering');
            else
                sound(app.flt_y,app.Fs);
            end
        end

        % Button pushed function: stopButton_3
        function stopButton_3Pushed(app, event)
            clear sound;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            str=['AfterFiltering\edited',num2str(app.t_f),'.wav'];
            audiowrite(str,app.flt_y,app.Fs);
            app.t_f=app.t_f+1;
            str=['edited',num2str(app.t_f),'.wav saved'];
            msgbox(str);

        end

        % Value changed function: trst_bdwth
        function trst_bdwthValueChanged(app, event)
            value = app.trst_bdwth.Value;
            if value<0
                msgbox('transition bandwidth must be more than 0');
                app.trst_bdwth.Value=app.trst_bdw;
            else
                app.trst_bdw=value;
            end
                
        end

        % Value changed function: rpEditField
        function rpEditFieldValueChanged(app, event)
            value = app.rpEditField.Value;
            app.rp=value;
        end

        % Value changed function: rsEditField
        function rsEditFieldValueChanged(app, event)
            value = app.rsEditField.Value;
            app.rs=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DomainAxes_L = uiaxes(app.UIFigure);
            title(app.DomainAxes_L, 'left')
            xlabel(app.DomainAxes_L, 't/s')
            ylabel(app.DomainAxes_L, 'a')
            app.SpectrumAxes_L = uiaxes(app.UIFigure);
            xlabel(app.SpectrumAxes_L, 'Freq')
            ylabel(app.SpectrumAxes_L, 'a')
            app.DomainAxes_R = uiaxes(app.UIFigure);
            title(app.DomainAxes_R, 'right')
            xlabel(app.DomainAxes_R, 't/s')
            ylabel(app.DomainAxes_R, 'a')
            app.SpectrumAxes_R = uiaxes(app.UIFigure);
            xlabel(app.SpectrumAxes_R, 'Freq')
            ylabel(app.SpectrumAxes_R, 'a')
            app.afterF_axes_l = uiaxes(app.UIFigure);
            xlabel(app.afterF_axes_l, 'freq')
            ylabel(app.afterF_axes_l, 'a')
            zlabel(app.afterF_axes_l, 'Z')
            app.afterF_axes_r = uiaxes(app.UIFigure);
            xlabel(app.afterF_axes_r, 'freq')
            ylabel(app.afterF_axes_r, 'a')
            zlabel(app.afterF_axes_r, 'Z')
            app.filterShow = uiaxes(app.UIFigure);
            title(app.filterShow, 'filter')
            xlabel(app.filterShow, 'Hz')
            zlabel(app.filterShow, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.fileTab = uitab(app.TabGroup);
            app.fileTab.Title = 'file';
            app.choosethefileButton = uibutton(app.fileTab, 'push');
            app.choosethefileButton.ButtonPushedFcn = createCallbackFcn(app, @choosethefileButtonPushed, true);
            app.choosethefileButton.Text = 'choose the file';
            app.uploadButton = uibutton(app.fileTab, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Text = 'upload';
            app.FileDisplay = uitextarea(app.fileTab);
            app.FileDisplay.Editable = 'off';
            app.realtimeTab = uitab(app.TabGroup);
            app.realtimeTab.Title = 'real-time';
            app.startButton = uibutton(app.realtimeTab, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.realtimeTab, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.timeTextAreaLabel = uilabel(app.realtimeTab);
            app.timeTextAreaLabel.Text = 'time';
            app.timeTextArea = uitextarea(app.realtimeTab);
            app.timeTextArea.Editable = 'off';
            app.sLabel = uilabel(app.realtimeTab);
            app.sLabel.Text = 's';
            app.inputsourceLabel = uilabel(app.UIFigure);
            app.inputsourceLabel.Text = 'input source ';
            app.TimedomainwaveformLabel = uilabel(app.UIFigure);
            app.TimedomainwaveformLabel.Text = 'Time domain waveform';
            app.SpectrumLabel = uilabel(app.UIFigure);
            app.SpectrumLabel.Text = 'Spectrum';
            app.filterButtonGroup = uibuttongroup(app.UIFigure);
            app.filterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @filterButtonGroupSelectionChanged, true);
            app.filterButtonGroup.Title = 'filter';
            app.LowpassfilterButton = uiradiobutton(app.filterButtonGroup);
            app.LowpassfilterButton.Text = 'Low pass filter';
            app.LowpassfilterButton.Value = true;
            app.HighpassfilterButton = uiradiobutton(app.filterButtonGroup);
            app.HighpassfilterButton.Text = 'High pass filter';
            app.BandpassfilterButton = uiradiobutton(app.filterButtonGroup);
            app.BandpassfilterButton.Text = 'Band pass filter';
            app.BandstopfilterButton = uiradiobutton(app.filterButtonGroup);
            app.BandstopfilterButton.Text = 'Band stop filter';
            app.Filt_Start = uibutton(app.UIFigure, 'push');
            app.Filt_Start.ButtonPushedFcn = createCallbackFcn(app, @Filt_StartButtonPushed, true);
            app.Filt_Start.Text = '滤波';
            app.currentfileTextAreaLabel = uilabel(app.UIFigure);
            app.currentfileTextAreaLabel.Text = 'current file';
            app.currentfile = uitextarea(app.UIFigure);
            app.currentfile.Editable = 'off';
            app.currentfile.Value = {'empty'};
            app.generateButton_T = uibutton(app.UIFigure, 'push');
            app.generateButton_T.ButtonPushedFcn = createCallbackFcn(app, @generateButton_TPushed, true);
            app.generateButton_T.Text = 'generate';
            app.generateButton_F = uibutton(app.UIFigure, 'push');
            app.generateButton_F.ButtonPushedFcn = createCallbackFcn(app, @generateButton_FPushed, true);
            app.generateButton_F.Text = 'generate';
            app.timeTextArea_2Label = uilabel(app.UIFigure);
            app.timeTextArea_2Label.Text = 'time';
            app.timeTextArea_2 = uitextarea(app.UIFigure);
            app.timeTextArea_2.Editable = 'off';
            app.afterfilteringLabel = uilabel(app.UIFigure);
            app.afterfilteringLabel.Text = 'after filtering ';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.stopButton_2 = uibutton(app.UIFigure, 'push');
            app.stopButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopButton_2Pushed, true);
            app.stopButton_2.Text = 'stop';
            app.fLEditFieldLabel = uilabel(app.UIFigure);
            app.fLEditFieldLabel.Text = 'f L';
            app.fLEditField = uieditfield(app.UIFigure, 'numeric');
            app.fLEditField.ValueChangedFcn = createCallbackFcn(app, @fLEditFieldValueChanged, true);
            app.fHEditFieldLabel = uilabel(app.UIFigure);
            app.fHEditFieldLabel.Text = 'f H';
            app.fHEditField = uieditfield(app.UIFigure, 'numeric');
            app.fHEditField.ValueChangedFcn = createCallbackFcn(app, @fHEditFieldValueChanged, true);
            app.bandwidthTextArea = uitextarea(app.UIFigure);
            app.bandwidthTextArea.Editable = 'off';
            app.bandwidthLabel = uilabel(app.UIFigure);
            app.bandwidthLabel.Text = 'bandwidth';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.palyButton = uibutton(app.UIFigure, 'push');
            app.palyButton.ButtonPushedFcn = createCallbackFcn(app, @palyButtonPushed, true);
            app.palyButton.Text = 'paly';
            app.stopButton_3 = uibutton(app.UIFigure, 'push');
            app.stopButton_3.ButtonPushedFcn = createCallbackFcn(app, @stopButton_3Pushed, true);
            app.stopButton_3.Text = 'stop';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.AfterfilteringLabel = uilabel(app.UIFigure);
            app.AfterfilteringLabel.Text = 'After filtering ';
            app.trst_bdwth = uieditfield(app.UIFigure, 'numeric');
            app.trst_bdwth.ValueChangedFcn = createCallbackFcn(app, @trst_bdwthValueChanged, true);
            app.transitionbandwidthLabel = uilabel(app.UIFigure);
            app.transitionbandwidthLabel.Text = 'transition bandwidth';
            app.rpEditFieldLabel = uilabel(app.UIFigure);
            app.rpEditFieldLabel.Text = 'rp';
            app.rpEditField = uieditfield(app.UIFigure, 'numeric');
            app.rpEditField.ValueChangedFcn = createCallbackFcn(app, @rpEditFieldValueChanged, true);
            app.rsEditFieldLabel = uilabel(app.UIFigure);
            app.rsEditFieldLabel.Text = 'rs';
            app.rsEditField = uieditfield(app.UIFigure, 'numeric');
            app.rsEditField.ValueChangedFcn = createCallbackFcn(app, @rsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end